classdef UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VNAUIFigure                     matlab.ui.Figure
        ErrorField                      matlab.ui.control.EditField
        StopFreq                        matlab.ui.control.NumericEditField
        StartFreq                       matlab.ui.control.NumericEditField
        RunVNAButton                    matlab.ui.control.Button
        FileNameEditField               matlab.ui.control.EditField
        FileNameEditFieldLabel          matlab.ui.control.Label
        HowlongbetweenMeasurementsEditField  matlab.ui.control.NumericEditField
        HowlongbetweenMeasurementsLabel  matlab.ui.control.Label
        Numberofpoints                  matlab.ui.control.NumericEditField
        NumberofpointsEditField_2Label  matlab.ui.control.Label
        HowManyMeasruementsEditField    matlab.ui.control.NumericEditField
        HowManyMeasruementsEditFieldLabel  matlab.ui.control.Label
        SelectPortsListBox              matlab.ui.control.ListBox
        SelectPortsListBoxLabel         matlab.ui.control.Label
        HowmanyAntennasEditField        matlab.ui.control.NumericEditField
        HowmanyAntennasEditFieldLabel   matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        FrequencyRangeGHzSlider         matlab.ui.control.RangeSlider
        FrequencyRangeGHzSliderLabel    matlab.ui.control.Label
        ChooseSettingsLabel             matlab.ui.control.Label
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    properties (Access = private)
        FinalTimeInSeconds 
    end
    
    methods (Access = private)
        
        
        function updateUI(app, currentVals, isFromSlider)

            if isFromSlider
            majTicks = app.FrequencyRangeGHzSlider.MajorTicks;
            minTicks = app.FrequencyRangeGHzSlider.MinorTicks;
            
            allTicks = unique([majTicks, minTicks]);
            
                if ~isempty(allTicks)
                    [~, lowIdx] = min(abs(allTicks - currentVals(1)));
                    snappedLow  = allTicks(lowIdx);
                    
                    [~, highIdx] = min(abs(allTicks - currentVals(2)));
                    snappedHigh = allTicks(highIdx);
                    
                    currentVals = [snappedLow, snappedHigh];
                end
            end


            app.FrequencyRangeGHzSlider.Value = currentVals; 
            lowVal  = currentVals(1);
            highVal = currentVals(2);
            
            sliderX     = app.FrequencyRangeGHzSlider.Position(1);
            sliderWidth = app.FrequencyRangeGHzSlider.Position(3);
            
            minLimit = app.FrequencyRangeGHzSlider.Limits(1);
            maxLimit = app.FrequencyRangeGHzSlider.Limits(2);
            totalRange = maxLimit - minLimit;
            
            normLowX = (lowVal - minLimit) / totalRange;
            newLowX  = sliderX + (normLowX * sliderWidth) - (app.StartFreq.Position(3) / 2);
            app.StartFreq.Position(1) = newLowX;            
            app.StartFreq.Value = lowVal;

            normHighX = (highVal - minLimit) / totalRange;
            newHighX  = sliderX + (normHighX * sliderWidth) - (app.StartFreq.Position(3) / 2);
            app.StopFreq.Position(1) = newHighX;
            app.StopFreq.Value = highVal;

        end
     
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DropDownValueChanged([]);
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.HowlongbetweenMeasurementsEditField.Value;
            selectedUnit = app.DropDown.Value;

            switch selectedUnit
                case 'Minutes'
                    app.FinalTimeInSeconds = value * 60;
                case 'Seconds'
                    app.FinalTimeInSeconds = value;
            end
        end

        % Value changed function: HowlongbetweenMeasurementsEditField
        function HowlongbetweenMeasurementsEditFieldValueChanged(app, event)
            app.DropDownValueChanged([]);
            
        end

        % Value changed function: FrequencyRangeGHzSlider
        function FrequencyRangeGHzSliderValueChanged(app, event)
            app.updateUI(event.Value, true);
        end

        % Value changed function: StartFreq
        function StartFreqValueChanged(app, event)
            newMin = app.StartFreq.Value;
            currentMax = app.FrequencyRangeGHzSlider.Value(2);
            sliderMinLimit = app.FrequencyRangeGHzSlider.Limits(1);
            
            if newMin < sliderMinLimit || newMin > currentMax
                app.StartFreq.Value = event.PreviousValue;
                return;
            end
            
            app.updateUI([newMin, currentMax], false);
        end

        % Value changed function: StopFreq
        function StopFreqValueChanged(app, event)
            newMax = app.StopFreq.Value;
            currentMin = app.FrequencyRangeGHzSlider.Value(1);
            sliderMaxLimit = app.FrequencyRangeGHzSlider.Limits(2);
            
            if newMax > sliderMaxLimit || newMax < currentMin
                app.StopFreq.Value = event.PreviousValue;
                return;
            end
            
            app.updateUI([currentMin, newMax], false);
        end

        % Button pushed function: RunVNAButton
        function RunVNAButtonPushed(app, event)
            sliderVals = app.FrequencyRangeGHzSlider.Value;
            startFreq = sliderVals(1)*1e9;
            stopFreq = sliderVals(2)*1e9;
            definePoints = app.Numberofpoints.Value;
            numMeasurements = app.HowManyMeasruementsEditField.Value;
            fileName = app.FileNameEditField.Value;
            fileName = string(fileName);
            numAntennas = app.HowmanyAntennasEditField.Value;
            ports = app.SelectPortsListBox.ValueIndex;
            waitTime = app.FinalTimeInSeconds;

            if numAntennas ~= length(ports)
                app.ErrorField.Visible = "on";
                return;
            else
                app.ErrorField.Visible = "off";
            end

            VNAUI(startFreq, stopFreq, definePoints, numMeasurements, fileName, numAntennas, ports, waitTime)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VNAUIFigure = uifigure('Visible', 'off');
            app.VNAUIFigure.Name = 'VNA';
            app.ChooseSettingsLabel = uilabel(app.VNAUIFigure);
            app.ChooseSettingsLabel.Text = 'Choose Settings';
            app.FrequencyRangeGHzSliderLabel = uilabel(app.VNAUIFigure);
            app.FrequencyRangeGHzSliderLabel.Text = 'Frequency Range (GHz)';
            app.FrequencyRangeGHzSlider = uislider(app.VNAUIFigure, 'range');
            app.FrequencyRangeGHzSlider.Limits = [0.5 6.5];
            app.FrequencyRangeGHzSlider.MajorTicks = [0.5 1 1.5 2 2.5 3 3.5 4 4.5 5 5.5 6 6.5];
            app.FrequencyRangeGHzSlider.ValueChangedFcn = createCallbackFcn(app, @FrequencyRangeGHzSliderValueChanged, true);
            app.FrequencyRangeGHzSlider.Value = [0.5 6.5];
            app.DropDown = uidropdown(app.VNAUIFigure);
            app.DropDown.Items = {'Seconds', 'Minutes'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Seconds';
            app.HowmanyAntennasEditFieldLabel = uilabel(app.VNAUIFigure);
            app.HowmanyAntennasEditFieldLabel.Text = 'How many Antennas';
            app.HowmanyAntennasEditField = uieditfield(app.VNAUIFigure, 'numeric');
            app.HowmanyAntennasEditField.Limits = [1 16];
            app.HowmanyAntennasEditField.Value = 1;
            app.SelectPortsListBoxLabel = uilabel(app.VNAUIFigure);
            app.SelectPortsListBoxLabel.Text = 'Select Ports';
            app.SelectPortsListBox = uilistbox(app.VNAUIFigure);
            app.SelectPortsListBox.Items = {'Port 1', 'Port 2', 'Port 3', 'Port 4', 'Port 5', 'Port 6', 'Port 7', 'Port 8', 'Port 9', 'Port 10', 'Port 11', 'Port 12', 'Port 13', 'Port 14', 'Port 15', 'Port 16'};
            app.SelectPortsListBox.Multiselect = 'on';
            app.SelectPortsListBox.Value = {'Port 1'};
            app.HowManyMeasruementsEditFieldLabel = uilabel(app.VNAUIFigure);
            app.HowManyMeasruementsEditFieldLabel.Text = 'How Many Measruements';
            app.HowManyMeasruementsEditField = uieditfield(app.VNAUIFigure, 'numeric');
            app.HowManyMeasruementsEditField.Limits = [1 Inf];
            app.HowManyMeasruementsEditField.Value = 1;
            app.NumberofpointsEditField_2Label = uilabel(app.VNAUIFigure);
            app.NumberofpointsEditField_2Label.Text = 'Number of points';
            app.Numberofpoints = uieditfield(app.VNAUIFigure, 'numeric');
            app.Numberofpoints.Limits = [101 1010];
            app.Numberofpoints.Value = 201;
            app.HowlongbetweenMeasurementsLabel = uilabel(app.VNAUIFigure);
            app.HowlongbetweenMeasurementsLabel.Text = 'How long between Measurements';
            app.HowlongbetweenMeasurementsEditField = uieditfield(app.VNAUIFigure, 'numeric');
            app.HowlongbetweenMeasurementsEditField.ValueChangedFcn = createCallbackFcn(app, @HowlongbetweenMeasurementsEditFieldValueChanged, true);
            app.HowlongbetweenMeasurementsEditField.Value = 1;
            app.FileNameEditFieldLabel = uilabel(app.VNAUIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.VNAUIFigure, 'text');
            app.RunVNAButton = uibutton(app.VNAUIFigure, 'push');
            app.RunVNAButton.ButtonPushedFcn = createCallbackFcn(app, @RunVNAButtonPushed, true);
            app.RunVNAButton.Text = 'Run VNA';
            app.StartFreq = uieditfield(app.VNAUIFigure, 'numeric');
            app.StartFreq.Limits = [0.5 6.4];
            app.StartFreq.ValueChangedFcn = createCallbackFcn(app, @StartFreqValueChanged, true);
            app.StartFreq.Value = 0.5;
            app.StopFreq = uieditfield(app.VNAUIFigure, 'numeric');
            app.StopFreq.Limits = [0.6 6.5];
            app.StopFreq.ValueChangedFcn = createCallbackFcn(app, @StopFreqValueChanged, true);
            app.StopFreq.Value = 6.5;
            app.ErrorField = uieditfield(app.VNAUIFigure, 'text');
            app.ErrorField.Visible = 'off';
            app.ErrorField.Value = 'Wrong Number of Ports';
            % Show the figure after all components are created
            app.VNAUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.VNAUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.VNAUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VNAUIFigure)
        end
    end
end