classdef UI_June9 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VNAUIFigure                    matlab.ui.Figure
        CalibrationConfirmed           matlab.ui.control.CheckBox
        CancelButton                   matlab.ui.control.Button
        SelectPortsonVNALabel          matlab.ui.control.Label
        CheckBox_15                    matlab.ui.control.CheckBox
        CheckBox_14                    matlab.ui.control.CheckBox
        CheckBox_13                    matlab.ui.control.CheckBox
        CheckBox_12                    matlab.ui.control.CheckBox
        CheckBox_11                    matlab.ui.control.CheckBox
        CheckBox_9                     matlab.ui.control.CheckBox
        CheckBox_8                     matlab.ui.control.CheckBox
        CheckBox_7                     matlab.ui.control.CheckBox
        CheckBox_6                     matlab.ui.control.CheckBox
        CheckBox_5                     matlab.ui.control.CheckBox
        CheckBox_2                     matlab.ui.control.CheckBox
        CheckBox_1                     matlab.ui.control.CheckBox
        NumberofAntennasTextArea       matlab.ui.control.TextArea
        NumberofAntennasTextAreaLabel  matlab.ui.control.Label
        RunVNAButton                   matlab.ui.control.Button
        EnterfilenameEditField         matlab.ui.control.EditField
        EnterfilenameEditFieldLabel    matlab.ui.control.Label
        HowlongbetweenMeasurementsEditField  matlab.ui.control.NumericEditField
        HowlongbetweenMeasurementsLabel  matlab.ui.control.Label
        NumberofmeasurementsEditField  matlab.ui.control.NumericEditField
        HowManyMeasruementsEditFieldLabel  matlab.ui.control.Label
        DropDown                       matlab.ui.control.DropDown
        ChooseSettingsLabel            matlab.ui.control.Label
        M9801A600Panel                 matlab.ui.container.Panel
        CheckBox_3                     matlab.ui.control.CheckBox
        CheckBox_4                     matlab.ui.control.CheckBox
        M9801A600Panel_2               matlab.ui.container.Panel
        CheckBox_10                    matlab.ui.control.CheckBox
        M9801A400Panel                 matlab.ui.container.Panel
        CheckBox_16                    matlab.ui.control.CheckBox
    end

    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    properties (Access = public)
        FinalTimeInSeconds 
        radioArray
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DropDownValueChanged([]);
            app.radioArray = zeros(16);
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.HowlongbetweenMeasurementsEditField.Value;
            selectedUnit = app.DropDown.Value;

            switch selectedUnit
                case 'Minute(s)'
                    app.FinalTimeInSeconds = value * 60;
                case 'Seconds'
                    app.FinalTimeInSeconds = value;
            end
        end

        % Value changed function: HowlongbetweenMeasurementsEditField
        function HowlongbetweenMeasurementsEditFieldValueChanged(app, event)
            app.DropDownValueChanged([]);
            
        end

        % Button pushed function: RunVNAButton
        function RunVNAButtonPushed(app, event)
      
            % collect values
            numMeasurements = app.NumberofmeasurementsEditField.Value;
            fileName = string(app.EnterfilenameEditField.Value);
            numAntennas = str2double(app.NumberofAntennasTextArea.Value);
            ports = find(app.radioArray);
            waitTime = app.FinalTimeInSeconds;

            % turn it red if they don't confirm
            % if app.CalibrationConfirmed ~= true
            %     app.CalibrationConfirmed.FontColor = 'red';
            %     pause;
            % end
                       
            % change format when running
            app.RunVNAButton.BackgroundColor = [0.2 0.2 0.2];
            app.RunVNAButton.FontColor = [0.96 0.96 0.96];
            app.RunVNAButton.Text = "Running";
            app.CancelButton.Visible = "on";

            % useful for testing: 
            %printinputs(numMeasurements, fileName, numAntennas, ports, waitTime);
            %pause(30);
            VNAUI_June8_2(numMeasurements, fileName, numAntennas, ports, waitTime);
            
            % change format when completed
            app.RunVNAButton.BackgroundColor = [0.96 0.96 0.96];
            app.RunVNAButton.FontColor = [0.13 0.13 0.13];
            app.RunVNAButton.Text = "Run VNA";
            app.CancelButton.Visible = 'off';
        end

        % Value changed function: CheckBox_1
        function CheckBox_1ValueChanged(app, event)
            value = app.CheckBox_1.Value;
            app.radioArray(1) = value;
            app.NumberofAntennasTextArea.Value = string(nnz(app.radioArray));
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            value = app.CheckBox_2.Value;
            app.radioArray(2) = value;
            app.NumberofAntennasTextArea.Value = string(nnz(app.radioArray));
        end

        % Value changed function: CheckBox_3
        function CheckBox_3ValueChanged(app, event)
            value = app.CheckBox_3.Value;
            app.radioArray(3) = value;
            app.NumberofAntennasTextArea.Value = string(nnz(app.radioArray));
        end

        % Value changed function: CheckBox_4
        function CheckBox_4ValueChanged(app, event)
            value = app.CheckBox_4.Value;
            app.radioArray(4) = value;
            app.NumberofAntennasTextArea.Value = string(nnz(app.radioArray));
        end

        % Value changed function: CheckBox_5
        function CheckBox_5ValueChanged(app, event)
            value = app.CheckBox_5.Value;
            app.radioArray(5) = value;
            app.NumberofAntennasTextArea.Value = string(nnz(app.radioArray));
        end

        % Value changed function: CheckBox_6
        function CheckBox_6ValueChanged(app, event)
            value = app.CheckBox_6.Value;
            app.radioArray(6) = value;
            app.NumberofAntennasTextArea.Value = string(nnz(app.radioArray));
        end

        % Value changed function: CheckBox_7
        function CheckBox_7ValueChanged(app, event)
            value = app.CheckBox_7.Value;
            app.radioArray(7) = value;
            app.NumberofAntennasTextArea.Value = string(nnz(app.radioArray));
        end

        % Value changed function: CheckBox_8
        function CheckBox_8ValueChanged(app, event)
            value = app.CheckBox_8.Value;
            app.radioArray(8) = value;
            app.NumberofAntennasTextArea.Value = string(nnz(app.radioArray));
        end

        % Value changed function: CheckBox_9
        function CheckBox_9ValueChanged(app, event)
            value = app.CheckBox_9.Value;
            app.radioArray(9) = value;
            app.NumberofAntennasTextArea.Value = string(nnz(app.radioArray));
        end

        % Value changed function: CheckBox_10
        function CheckBox_10ValueChanged(app, event)
            value = app.CheckBox_10.Value;
            app.radioArray(10) = value;
            app.NumberofAntennasTextArea.Value = string(nnz(app.radioArray));
        end

        % Value changed function: CheckBox_11
        function CheckBox_11ValueChanged(app, event)
            value = app.CheckBox_11.Value;
            app.radioArray(11) = value;
            app.NumberofAntennasTextArea.Value = string(nnz(app.radioArray));
        end

        % Value changed function: CheckBox_12
        function CheckBox_12ValueChanged(app, event)
            value = app.CheckBox_12.Value;
            app.radioArray(12) = value;
            app.NumberofAntennasTextArea.Value = string(nnz(app.radioArray));
        end

        % Value changed function: CheckBox_13
        function CheckBox_13ValueChanged(app, event)
            value = app.CheckBox_13.Value;
            app.radioArray(13) = value;
            app.NumberofAntennasTextArea.Value = string(nnz(app.radioArray));
        end

        % Value changed function: CheckBox_14
        function CheckBox_14ValueChanged(app, event)
            value = app.CheckBox_14.Value;
            app.radioArray(14) = value;
            app.NumberofAntennasTextArea.Value = string(nnz(app.radioArray));
        end

        % Value changed function: CheckBox_15
        function CheckBox_15ValueChanged(app, event)
            value = app.CheckBox_15.Value;
            app.radioArray(15) = value;
            app.NumberofAntennasTextArea.Value = string(nnz(app.radioArray));
        end

        % Value changed function: CheckBox_16
        function CheckBox_16ValueChanged(app, event)
            value = app.CheckBox_16.Value;
            app.radioArray(16) = value;
            app.NumberofAntennasTextArea.Value = string(nnz(app.radioArray));
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            exit;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VNAUIFigure = uifigure('Visible', 'off');
            app.VNAUIFigure.Name = 'VNA';
            app.M9801A400Panel = uipanel(app.VNAUIFigure);
            app.M9801A400Panel.Title = 'M9801A-400 ';
            app.CheckBox_16 = uicheckbox(app.M9801A400Panel);
            app.CheckBox_16.ValueChangedFcn = createCallbackFcn(app, @CheckBox_16ValueChanged, true);
            app.CheckBox_16.Text = '16';
            app.M9801A600Panel_2 = uipanel(app.VNAUIFigure);
            app.M9801A600Panel_2.Title = 'M9801A-600 ';
            app.CheckBox_10 = uicheckbox(app.M9801A600Panel_2);
            app.CheckBox_10.ValueChangedFcn = createCallbackFcn(app, @CheckBox_10ValueChanged, true);
            app.CheckBox_10.Text = '10';
            app.M9801A600Panel = uipanel(app.VNAUIFigure);
            app.M9801A600Panel.Title = 'M9801A-600 ';
            app.CheckBox_4 = uicheckbox(app.M9801A600Panel);
            app.CheckBox_4.ValueChangedFcn = createCallbackFcn(app, @CheckBox_4ValueChanged, true);
            app.CheckBox_4.Text = '4';
            app.CheckBox_3 = uicheckbox(app.M9801A600Panel);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @CheckBox_3ValueChanged, true);
            app.CheckBox_3.Text = '3';
            app.ChooseSettingsLabel = uilabel(app.VNAUIFigure);
            app.ChooseSettingsLabel.Text = 'Choose Settings';
            app.DropDown = uidropdown(app.VNAUIFigure);
            app.DropDown.Items = {'Seconds', 'Minute(s)'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Minute(s)';
            app.HowManyMeasruementsEditFieldLabel = uilabel(app.VNAUIFigure);
            app.HowManyMeasruementsEditFieldLabel.Text = 'Number of measurements';
            app.NumberofmeasurementsEditField = uieditfield(app.VNAUIFigure, 'numeric');
            app.NumberofmeasurementsEditField.Limits = [1 Inf];
            app.NumberofmeasurementsEditField.Value = 1;
            app.HowlongbetweenMeasurementsLabel = uilabel(app.VNAUIFigure);
            app.HowlongbetweenMeasurementsLabel.Text = 'Time between measurements';
            app.HowlongbetweenMeasurementsEditField = uieditfield(app.VNAUIFigure, 'numeric');
            app.HowlongbetweenMeasurementsEditField.ValueChangedFcn = createCallbackFcn(app, @HowlongbetweenMeasurementsEditFieldValueChanged, true);
            app.HowlongbetweenMeasurementsEditField.Value = 5;
            app.EnterfilenameEditFieldLabel = uilabel(app.VNAUIFigure);
            app.EnterfilenameEditFieldLabel.Text = 'Enter file name';
            app.EnterfilenameEditField = uieditfield(app.VNAUIFigure, 'text');
            app.RunVNAButton = uibutton(app.VNAUIFigure, 'push');
            app.RunVNAButton.ButtonPushedFcn = createCallbackFcn(app, @RunVNAButtonPushed, true);
            app.RunVNAButton.Text = 'Run VNA';
            app.NumberofAntennasTextAreaLabel = uilabel(app.VNAUIFigure);
            app.NumberofAntennasTextAreaLabel.Text = 'Number of Antennas';
            app.NumberofAntennasTextArea = uitextarea(app.VNAUIFigure);
            app.NumberofAntennasTextArea.Editable = 'off';
            app.CheckBox_1 = uicheckbox(app.VNAUIFigure);
            app.CheckBox_1.ValueChangedFcn = createCallbackFcn(app, @CheckBox_1ValueChanged, true);
            app.CheckBox_1.Text = '1';
            app.CheckBox_2 = uicheckbox(app.VNAUIFigure);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Text = '2';
            app.CheckBox_5 = uicheckbox(app.VNAUIFigure);
            app.CheckBox_5.ValueChangedFcn = createCallbackFcn(app, @CheckBox_5ValueChanged, true);
            app.CheckBox_5.Text = '5';
            app.CheckBox_6 = uicheckbox(app.VNAUIFigure);
            app.CheckBox_6.ValueChangedFcn = createCallbackFcn(app, @CheckBox_6ValueChanged, true);
            app.CheckBox_6.Text = '6';
            app.CheckBox_7 = uicheckbox(app.VNAUIFigure);
            app.CheckBox_7.ValueChangedFcn = createCallbackFcn(app, @CheckBox_7ValueChanged, true);
            app.CheckBox_7.Text = '7';
            app.CheckBox_8 = uicheckbox(app.VNAUIFigure);
            app.CheckBox_8.ValueChangedFcn = createCallbackFcn(app, @CheckBox_8ValueChanged, true);
            app.CheckBox_8.Text = '8';
            app.CheckBox_9 = uicheckbox(app.VNAUIFigure);
            app.CheckBox_9.ValueChangedFcn = createCallbackFcn(app, @CheckBox_9ValueChanged, true);
            app.CheckBox_9.Text = '9';
            app.CheckBox_11 = uicheckbox(app.VNAUIFigure);
            app.CheckBox_11.ValueChangedFcn = createCallbackFcn(app, @CheckBox_11ValueChanged, true);
            app.CheckBox_11.Text = '11';
            app.CheckBox_12 = uicheckbox(app.VNAUIFigure);
            app.CheckBox_12.ValueChangedFcn = createCallbackFcn(app, @CheckBox_12ValueChanged, true);
            app.CheckBox_12.Text = '12';
            app.CheckBox_13 = uicheckbox(app.VNAUIFigure);
            app.CheckBox_13.ValueChangedFcn = createCallbackFcn(app, @CheckBox_13ValueChanged, true);
            app.CheckBox_13.Text = '13';
            app.CheckBox_14 = uicheckbox(app.VNAUIFigure);
            app.CheckBox_14.ValueChangedFcn = createCallbackFcn(app, @CheckBox_14ValueChanged, true);
            app.CheckBox_14.Text = '14';
            app.CheckBox_15 = uicheckbox(app.VNAUIFigure);
            app.CheckBox_15.ValueChangedFcn = createCallbackFcn(app, @CheckBox_15ValueChanged, true);
            app.CheckBox_15.Text = '15';
            app.SelectPortsonVNALabel = uilabel(app.VNAUIFigure);
            app.SelectPortsonVNALabel.Text = 'Select ports used for calibration on VNA';
            app.CancelButton = uibutton(app.VNAUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Visible = 'off';
            app.CancelButton.Text = 'Cancel';
            app.CalibrationConfirmed = uicheckbox(app.VNAUIFigure);
            app.CalibrationConfirmed.Text = 'I have completed eCal calibration and set the number of sweep points and frequency range';
            % Show the figure after all components are created
            app.VNAUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI_June9

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.VNAUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.VNAUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VNAUIFigure)
        end
    end
end